classdef Graficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GraficarButton            matlab.ui.control.Button
        PeriodoEditField          matlab.ui.control.NumericEditField
        PeriodoEditFieldLabel     matlab.ui.control.Label
        FrecuenciaEditField       matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel  matlab.ui.control.Label
        AmplitudEditField         matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel    matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x = linspace(-2*pi,2*pi,100);
        A = 1;
        B = 1;
        C = 0;
        
    end
    
    methods (Access = private)
        
        function y = calcula_sin(app)
            y = app.A * sin(app.B * app.x + app.C);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot(app.UIAxes, app.x, app.calcula_sin());
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            app.A = app.AmplitudEditField.Value;
            app.B = app.FrecuenciaEditField.Value;
            app.C = app.PeriodoEditField.Value;
            plot(app.UIAxes, app.x, app.calcula_sin());
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'sin')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-6.28318530717959 6.28318530717959];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [-6.28318530717959 -3.14159265358979 0 3.14159265358979 6.28318530717959];
            app.AmplitudEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudEditField.Limits = [0 10];
            app.AmplitudEditField.Value = 1;
            app.FrecuenciaEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaEditField.Limits = [0 3.14159265358979];
            app.FrecuenciaEditField.Value = 1;
            app.PeriodoEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodoEditFieldLabel.Text = 'Periodo';
            app.PeriodoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodoEditField.Limits = [-6.28318530717959 6.28318530717959];
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Graficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end