classdef graficas2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        COSSwitch              matlab.ui.control.ToggleSwitch
        COSSwitchLabel         matlab.ui.control.Label
        PeriodoSlider          matlab.ui.control.Slider
        PeriodoSliderLabel     matlab.ui.control.Label
        FrecuenciaSlider       matlab.ui.control.Slider
        FrecuenciaSliderLabel  matlab.ui.control.Label
        AmplitudSlider         matlab.ui.control.Slider
        AmplitudSliderLabel    matlab.ui.control.Label
        SINSwitch              matlab.ui.control.ToggleSwitch
        SINSwitchLabel         matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x = linspace(-4*pi,4*pi,100);
        A = 1;
        B = 1;
        C = 0;
        showSin = true;
        showCos = true;
    end
    
    methods (Access = private)
        
        function grafica(app)
            cla(app.UIAxes);
            hold(app.UIAxes, "on");
            if app.showSin
                y = app.A*sin(app.B*app.x + app.C);
                plot(app.UIAxes, app.x, y);
            end
            if app.showCos
                y = app.A*cos(app.B*app.x + app.C);
                plot(app.UIAxes, app.x, y);
            end
            hold(app.UIAxes, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.grafica();
        end

        % Value changing function: AmplitudSlider
        function AmplitudSliderValueChanging(app, event)
            app.A = event.Value;
            app.grafica();
        end

        % Value changing function: FrecuenciaSlider
        function FrecuenciaSliderValueChanging(app, event)
            app.B = event.Value;
            app.grafica();
        end

        % Value changing function: PeriodoSlider
        function PeriodoSliderValueChanging(app, event)
            app.C = event.Value;
            app.grafica();
        end

        % Value changed function: SINSwitch
        function SINSwitchValueChanged(app, event)
            value = app.SINSwitch.Value;
            if value == "On"
                app.showSin = true;
            else
                app.showSin = false;
            end
            app.grafica();
        end

        % Value changed function: COSSwitch
        function COSSwitchValueChanged(app, event)
            value = app.COSSwitch.Value;
            if value == "On"
                app.showCos = true;
            else
                app.showCos = false;
            end
            app.grafica();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graficas')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-12.5663706143592 12.5663706143592];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [-12.5663706143592 -6.28318530717959 0 6.28318530717959 12.5663706143592];
            app.UIAxes.YTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.SINSwitchLabel = uilabel(app.UIFigure);
            app.SINSwitchLabel.Text = 'SIN';
            app.SINSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SINSwitch.ValueChangedFcn = createCallbackFcn(app, @SINSwitchValueChanged, true);
            app.SINSwitch.Value = 'On';
            app.AmplitudSliderLabel = uilabel(app.UIFigure);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.AmplitudSlider = uislider(app.UIFigure);
            app.AmplitudSlider.Limits = [0 10];
            app.AmplitudSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudSliderValueChanging, true);
            app.AmplitudSlider.Value = 1;
            app.FrecuenciaSliderLabel = uilabel(app.UIFigure);
            app.FrecuenciaSliderLabel.Text = 'Frecuencia';
            app.FrecuenciaSlider = uislider(app.UIFigure);
            app.FrecuenciaSlider.Limits = [0 10];
            app.FrecuenciaSlider.ValueChangingFcn = createCallbackFcn(app, @FrecuenciaSliderValueChanging, true);
            app.FrecuenciaSlider.Value = 1;
            app.PeriodoSliderLabel = uilabel(app.UIFigure);
            app.PeriodoSliderLabel.Text = 'Periodo';
            app.PeriodoSlider = uislider(app.UIFigure);
            app.PeriodoSlider.Limits = [0 10];
            app.PeriodoSlider.ValueChangingFcn = createCallbackFcn(app, @PeriodoSliderValueChanging, true);
            app.COSSwitchLabel = uilabel(app.UIFigure);
            app.COSSwitchLabel.Text = 'COS';
            app.COSSwitch = uiswitch(app.UIFigure, 'toggle');
            app.COSSwitch.ValueChangedFcn = createCallbackFcn(app, @COSSwitchValueChanged, true);
            app.COSSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graficas2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end