classdef otraapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xp= 0.5;
        yp = 0.5;
        x= -0.5;
        y = -0.5;
        theta = (2*pi)/60;
        linea;
        vector = [0.5,0.5;
                      0.5,1;
                      1,1];
        M;
    end
    
    methods (Access = private)
        
        function reloj(app)
            app.vector = app.M*app.vector;
            set(app.linea, "XData", app.vector(1,:));
            set(app.linea, "YData", app.vector(2,:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.linea = plot(app.UIAxes, app.vector(1,:),app.vector(2,:));
            [h,m,s]=hms(datetime("now"));
            M=[cos(app.theta*s), sin(app.theta*s), app.x*cos(app.theta*s)+app.y*sin(app.theta*s)+app.xp;
                -sin(app.theta*s), cos(app.theta*s), -app.x*sin(app.theta*s)+app.y*cos(app.theta*s)+app.yp;
                0,0,1];
            
            app.M=[cos(app.theta), sin(app.theta), app.x*cos(app.theta)+app.y*sin(app.theta)+app.xp;
                -sin(app.theta), cos(app.theta), -app.x*sin(app.theta)+app.y*cos(app.theta)+app.yp;
                0,0,1];
            
            app.vector = M*app.vector;
            set(app.linea, "XData", app.vector(1,:));
            set(app.linea, "YData", app.vector(2,:));

            tm = timer("TasksToExecute",inf,"TimerFcn",@(~,~)reloj(app),"ExecutionMode","fixedRate","Period",1);
            tm.start();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = otraapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end